classdef PlottingFromCSV < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        StatsLabel               matlab.ui.control.Label
        PlotButton               matlab.ui.control.Button
        Column2DropDown          matlab.ui.control.DropDown
        Column2DropDownLabel     matlab.ui.control.Label
        Column1DropDown          matlab.ui.control.DropDown
        Column1DropDownLabel     matlab.ui.control.Label
        PlotTypeDropDown         matlab.ui.control.DropDown
        PlotTypeDropDownLabel    matlab.ui.control.Label
        FileLabel                matlab.ui.control.Label
        LoadTextFileButton       matlab.ui.control.Button
        LoadCSVButton            matlab.ui.control.Button
        PlotPanel                matlab.ui.container.Panel
    end

    properties (Access = private)
        T % Table read from the CSV file
        Text % Contents of the text file
    end

    methods (Access = private)

        function updateColumnLabels(app)
            switch app.PlotTypeDropDown.Value
                case {'Bar Plot', 'Pie Chart'}
                    labels = {'Labels', 'Values'};
                case {'Line Plot', 'Scatter Plot'}
                    labels = {'X', 'Y'};
                case {'Box Plot', 'Histogram'}
                    labels = {'', 'Data'};
                case 'Geographic Plot'
                    labels = {'Latitude', 'Longitude'};
                otherwise
                    labels = {'', ''};
            end
            app.Column1DropDownLabel.Text = labels{1};
            app.Column1DropDown.Enable = ~isempty(labels{1});
            app.Column2DropDownLabel.Text = labels{2};
            app.Column2DropDown.Enable = ~isempty(labels{2});
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadCSVButton
        function LoadCSVButtonPushed(app, event)
            [f,p] = uigetfile('*.csv');

            if isequal(f,0)
                return;
            end

            app.T = readtable(fullfile(p,f));
            names = app.T.Properties.VariableNames;
            app.FileLabel.Text = f;

            % Column drop-downs list the column names; Value is the column number
            app.Column1DropDown.Items = names;
            app.Column1DropDown.ItemsData = 1:numel(names);
            app.Column2DropDown.Items = names;
            app.Column2DropDown.ItemsData = 1:numel(names);
            app.Column2DropDown.Value = min(2, numel(names));
        end

        % Button pushed function: LoadTextFileButton
        function LoadTextFileButtonPushed(app, event)
            [f,p] = uigetfile('*.txt');

            if isequal(f,0)
                return;
            end

            app.Text = fileread(fullfile(p,f));
            app.FileLabel.Text = f;
            app.PlotTypeDropDown.Value = 'Word Cloud';
            updateColumnLabels(app);
        end

        % Value changed function: PlotTypeDropDown
        function PlotTypeDropDownValueChanged(app, event)
            updateColumnLabels(app);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            delete(app.PlotPanel.Children);
            app.StatsLabel.Text = '';
            plotType = app.PlotTypeDropDown.Value;

            % Word cloud from the text file
            if strcmp(plotType, 'Word Cloud')
                if isempty(app.Text)
                    uialert(app.UIFigure, 'Please load a text file first.', 'No Text File');
                    return;
                end
                t = replace(app.Text,[".","?","!",",",";",":"]," ");
                w = split(t);
                w = w(w ~= "");
                wc = wordcloud(app.PlotPanel, categorical(w));
                wc.Title = 'Word Cloud';
                return;
            end

            if isempty(app.T)
                uialert(app.UIFigure, 'Please load a CSV file first.', 'No CSV File');
                return;
            end

            T = app.T;
            a = app.Column1DropDown.Value;
            b = app.Column2DropDown.Value;

            % Geographic plot needs geographic axes
            if strcmp(plotType, 'Geographic Plot')
                gx = geoaxes(app.PlotPanel);
                geoplot(gx, T{:,a}, T{:,b}, 'r-o');
                geobasemap(gx, 'streets');
                title(gx, 'Geographic Plot');
                return;
            end

            ax = uiaxes(app.PlotPanel);
            ax.Position = [10 10 600 480];

            switch plotType
                case 'Bar Plot'
                    bar(ax, T{:,b});
                    xticks(ax, 1:height(T));
                    xticklabels(ax, string(T{:,a}));

                case 'Line Plot'
                    plot(ax, T{:,a}, T{:,b}, '-o');
                    xlabel(ax, T.Properties.VariableNames{a});
                    ylabel(ax, T.Properties.VariableNames{b});
                    grid(ax, 'on');

                case 'Scatter Plot'
                    scatter(ax, T{:,a}, T{:,b}, 'filled');
                    xlabel(ax, T.Properties.VariableNames{a});
                    ylabel(ax, T.Properties.VariableNames{b});
                    grid(ax, 'on');

                case 'Box Plot'
                    boxchart(ax, T{:,b});
                    ylabel(ax, T.Properties.VariableNames{b});
                    grid(ax, 'on');

                case 'Histogram'
                    d = T{:,b};
                    histogram(ax, d);
                    xlabel(ax, T.Properties.VariableNames{b});
                    ylabel(ax, 'Frequency');
                    app.StatsLabel.Text = sprintf('Mean %.2f Median %.2f Std %.2f Min %.2f Max %.2f', ...
                        mean(d), median(d), std(d), min(d), max(d));

                case 'Pie Chart'
                    pie(ax, T{:,b}, cellstr(string(T{:,a})));
            end

            title(ax, plotType);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Plotting from CSV';
            app.PlotPanel = uipanel(app.UIFigure);
            app.LoadCSVButton = uibutton(app.UIFigure, 'push');
            app.LoadCSVButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCSVButtonPushed, true);
            app.LoadCSVButton.Text = 'Load CSV';
            app.LoadTextFileButton = uibutton(app.UIFigure, 'push');
            app.LoadTextFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTextFileButtonPushed, true);
            app.LoadTextFileButton.Text = 'Load Text File';
            app.FileLabel = uilabel(app.UIFigure);
            app.FileLabel.Text = 'No file loaded';
            app.PlotTypeDropDownLabel = uilabel(app.UIFigure);
            app.PlotTypeDropDownLabel.Text = 'Plot';
            app.PlotTypeDropDown = uidropdown(app.UIFigure);
            app.PlotTypeDropDown.Items = {'Bar Plot', 'Line Plot', 'Scatter Plot', 'Box Plot', 'Histogram', 'Pie Chart', 'Geographic Plot', 'Word Cloud'};
            app.PlotTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotTypeDropDownValueChanged, true);
            app.PlotTypeDropDown.Value = 'Bar Plot';
            app.Column1DropDownLabel = uilabel(app.UIFigure);
            app.Column1DropDownLabel.Text = 'Labels';
            app.Column1DropDown = uidropdown(app.UIFigure);
            app.Column1DropDown.Items = {};
            app.Column2DropDownLabel = uilabel(app.UIFigure);
            app.Column2DropDownLabel.Text = 'Values';
            app.Column2DropDown = uidropdown(app.UIFigure);
            app.Column2DropDown.Items = {};
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.StatsLabel = uilabel(app.UIFigure);
            app.StatsLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlottingFromCSV

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end