classdef EdgeDetection < matlab.apps.AppBase
    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ExecutemethodButton  matlab.ui.control.Button
        DropDown             matlab.ui.control.DropDown
        DropDownLabel        matlab.ui.control.Label
        LoadImageButton      matlab.ui.control.Button
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Image % Loaded grayscale image
    end

    % Callbacks that handle component events
    methods (Access = private)
        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            try
                [filename, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp', 'Image Files'}, 'Select Image');
                if isequal(filename, 0)
                    return;
                end
                img = imread(fullfile(path, filename));
                if size(img, 3) == 3
                    img = rgb2gray(img);
                end
                app.Image = img;
                % Show in Original Image area
                imshow(img, 'Parent', app.UIAxes);
                title(app.UIAxes, 'Original Image', 'FontSize', 14);
            catch ME
                uialert(app.UIFigure, ['Error loading image: ' ME.message], 'Error');
            end
        end
        % Button pushed function: ExecutemethodButton
        function ExecutemethodButtonPushed(app, event)
            try
                % Get current image
                currentImage = app.Image;
                if isempty(currentImage)
                    uialert(app.UIFigure, 'Please load an image first!', 'Warning', 'Icon', 'warning');
                    return;
                end
                % Get selected method from Drop Down
                method = app.DropDown.Value;
                % Apply edge detection
                switch method
                    case 'Sobel'
                        edges = edge(currentImage, 'Sobel');
                        titleText = 'Sobel';
                    case 'Prewitt'
                        edges = edge(currentImage, 'Prewitt');
                        titleText = 'Prewitt';
                    case 'Roberts'
                        edges = edge(currentImage, 'Roberts');
                        titleText = 'Roberts';
                    case 'Log'
                        edges = edge(currentImage, 'log');
                        titleText = 'Laplacian of Gaussian';
                    case 'Canny'
                        edges = edge(currentImage, 'Canny');
                        titleText = 'Canny';
                    case 'Zero Crossing'
                        edges = edge(currentImage, 'zerocross');
                        titleText = 'Zero Crossing';
                    otherwise
                        edges = currentImage;
                        titleText = 'Original';
                end
                % Show result in Resultant Image area
                imshow(edges, 'Parent', app.UIAxes2);
                title(app.UIAxes2, titleText, 'FontSize', 14);
            catch ME
                uialert(app.UIFigure, ['Error: ' ME.message], 'Error');
            end
        end
    end
    % Component initialization
    methods (Access = private)
        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Edge Detection Techniques';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Resultant Image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Method';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Sobel', 'Prewitt', 'Roberts', 'Log', 'Canny', 'Zero Crossing'};
            app.DropDown.Value = 'Sobel';
            app.ExecutemethodButton = uibutton(app.UIFigure, 'push');
            app.ExecutemethodButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutemethodButtonPushed, true);
            app.ExecutemethodButton.Text = 'Execute method';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end
    % App creation and deletion
    methods (Access = public)
        % Construct app
        function app = EdgeDetection
            % Create UIFigure and components
            createComponents(app)
            % Register the app with App Designer
            registerApp(app, app.UIFigure)
            if nargout == 0
                clear app
            end
        end
        % Code that executes before app deletion
        function delete(app)
            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end